classdef MainPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Panel_5                     matlab.ui.container.Panel
        DropDown_5                  matlab.ui.control.DropDown
        Panel_4                     matlab.ui.container.Panel
        DropDown_6                  matlab.ui.control.DropDown
        Panel_6                     matlab.ui.container.Panel
        Button_4                    matlab.ui.control.Button
        Panel_8                     matlab.ui.container.Panel
        DropDown_8                  matlab.ui.control.DropDown
        Tab_3                       matlab.ui.container.Tab
        Button_5                    matlab.ui.control.Button
        Panel_7                     matlab.ui.container.Panel
        DropDown_7                  matlab.ui.control.DropDown
        Tab_2                       matlab.ui.container.Tab
        Button_3                    matlab.ui.control.Button
        TrainButton                 matlab.ui.control.Button
        SplitDataPanel              matlab.ui.container.Panel
        TrainEditFieldLabel         matlab.ui.control.Label
        TrainEditField              matlab.ui.control.NumericEditField
        ValidationEditFieldLabel    matlab.ui.control.Label
        ValidationEditField         matlab.ui.control.NumericEditField
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Tree                        matlab.ui.container.Tree
        Node                        matlab.ui.container.TreeNode
        Node2                       matlab.ui.container.TreeNode
        Node3                       matlab.ui.container.TreeNode
        Node4                       matlab.ui.container.TreeNode
        Label_2                     matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        HelpTab                     matlab.ui.container.Tab
        DocumentationAboutthisAppButton  matlab.ui.control.Button
        MathWorksDocumentationTransferLearningButton  matlab.ui.control.Button
        ExportTab                   matlab.ui.container.Tab
        MATLABCodeGenerationButton  matlab.ui.control.Button
        ExportToWorkspaceButton     matlab.ui.control.Button
        ExportToONNXButton          matlab.ui.control.Button
        GPUCoder                    matlab.ui.control.Button
        ExportToMatFile             matlab.ui.control.Button
        Panel_2                     matlab.ui.container.Panel
        StatusofRunningLampLabel    matlab.ui.control.Label
        StatusofRunningLamp         matlab.ui.control.Lamp
        TextArea_2                  matlab.ui.control.TextArea
        Panel_3                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        CommandLogLabel             matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        Panel_9                     matlab.ui.container.Panel
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            timeDomain = TimeDomain;
            [timeDomain.dataLoadNum, timeDomain.subjectNum, timeDomain.trailNum,timeDomain.channelNum]= DataLoadFunction(timeDomain);
            for k = 1:timeDomain.subjectNum
                dropDown6Items{k} = ['成员', num2str(k)];
            end
            app.DropDown_6.Items = dropDown6Items;
            for k = 1:timeDomain.trailNum
                dropDown8Items{k} = ['实验', num2str(k)];
            end
            app.DropDown_8.Items = dropDown8Items;
            for k = 1:timeDomain.channelNum               
                dropDown5Items{k} = ['通道', num2str(k)]; %#ok<*AGROW> 
                if k==timeDomain.channelNum; dropDown5Items{k+1} = '所有通道'; end
            end
            app.DropDown_5.Items = dropDown5Items;
            timeDomain.dataDispNum = DataDisplay(timeDomain, app.TextArea, app.DropDown_6.Value,app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);
            setappdata(app.Button_4, 'TimeDomain', timeDomain);
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            timeDomain = getappdata(app.Button_4, 'TimeDomain');
            timeDomain.dataDispNum = DataDisplay(timeDomain, app.TextArea, app.DropDown_6.Value, app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            timeDomain = getappdata(app.Button_4, 'TimeDomain');
            timeDomain.dataDispNum = DataDisplay(timeDomain, app.TextArea, app.DropDown_6.Value, app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);
        end

        % Value changed function: DropDown_8
        function DropDown_8ValueChanged(app, event)
            timeDomain = getappdata(app.Button_4, 'TimeDomain');
            timeDomain.dataDispNum = DataDisplay(timeDomain, app.TextArea, app.DropDown_6.Value, app.DropDown_8.Value, app.DropDown_5.Value);
            plot(app.UIAxes, timeDomain.dataDispNum);       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '时域信号';
            app.Panel_5 = uipanel(app.Tab);
            app.Panel_5.Title = '通道选择';
            app.DropDown_5 = uidropdown(app.Panel_5);
            app.DropDown_5.Items = {'', ''};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '';
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '被试选择';
            app.DropDown_6 = uidropdown(app.Panel_4);
            app.DropDown_6.Items = {};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = {};
            app.Panel_6 = uipanel(app.Tab);
            app.Button_4 = uibutton(app.Panel_6, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Tag = 'DataLoadButton';
            app.Button_4.Icon = 'TimeDomainData.png';
            app.Button_4.Text = '输入信号';
            app.Panel_8 = uipanel(app.Tab);
            app.Panel_8.Title = '实验选择';
            app.DropDown_8 = uidropdown(app.Panel_8);
            app.DropDown_8.Items = {};
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_8ValueChanged, true);
            app.DropDown_8.Value = {};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '特征提取';
            app.Button_5 = uibutton(app.Tab_3, 'push');
            app.Button_5.Icon = 'FeatureExtractionButton.png';
            app.Button_5.Text = '特征提取';
            app.Panel_7 = uipanel(app.Tab_3);
            app.Panel_7.Title = '特征选择';
            app.DropDown_7 = uidropdown(app.Panel_7);
            app.DropDown_7.Items = {};
            app.DropDown_7.Value = {};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '数据分类';
            app.Button_3 = uibutton(app.Tab_2, 'push');
            app.Button_3.Icon = 'icon_large_intuser.png';
            app.Button_3.Text = '数据导入';
            app.TrainButton = uibutton(app.Tab_2, 'push');
            app.TrainButton.Icon = '1_0FlvitTZnPKh8qkJ7UPLeQ.png';
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.SplitDataPanel = uipanel(app.Tab_2);
            app.SplitDataPanel.Title = 'Split Data';
            app.TrainEditFieldLabel = uilabel(app.SplitDataPanel);
            app.TrainEditFieldLabel.Text = 'Train (%)';
            app.TrainEditField = uieditfield(app.SplitDataPanel, 'numeric');
            app.TrainEditField.Limits = [0 99];
            app.TrainEditField.Enable = 'off';
            app.TrainEditField.Value = 70;
            app.ValidationEditFieldLabel = uilabel(app.SplitDataPanel);
            app.ValidationEditFieldLabel.Text = 'Validation (%)';
            app.ValidationEditField = uieditfield(app.SplitDataPanel, 'numeric');
            app.ValidationEditField.Limits = [0 99];
            app.ValidationEditField.Enable = 'off';
            app.ValidationEditField.Value = 30;
            app.ButtonGroup = uibuttongroup(app.Tab_2);
            app.ButtonGroup.Title = '分类器选择';
            app.Tree = uitree(app.ButtonGroup);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Label_2 = uilabel(app.ButtonGroup);
            app.Label_2.Text = '最小值';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.Label_3 = uilabel(app.ButtonGroup);
            app.Label_3.Text = '最大值';
            app.EditField_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.DocumentationAboutthisAppButton = uibutton(app.HelpTab, 'push');
            app.DocumentationAboutthisAppButton.Icon = '057_-_Document_Info-512.png';
            app.DocumentationAboutthisAppButton.Text = 'Documentation About this App';
            app.MathWorksDocumentationTransferLearningButton = uibutton(app.HelpTab, 'push');
            app.MathWorksDocumentationTransferLearningButton.Icon = 'Product-documentation-icon.png';
            app.MathWorksDocumentationTransferLearningButton.Text = {'MathWorks Documentation'; '(Transfer Learning)'};
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.MATLABCodeGenerationButton = uibutton(app.ExportTab, 'push');
            app.MATLABCodeGenerationButton.Icon = 'code-icon-png-free147-4bf3-b748-6cea8c83ad71.png';
            app.MATLABCodeGenerationButton.Enable = 'off';
            app.MATLABCodeGenerationButton.Text = 'MATLAB Code Generation';
            app.ExportToWorkspaceButton = uibutton(app.ExportTab, 'push');
            app.ExportToWorkspaceButton.Icon = 'Matlab_Logo.png';
            app.ExportToWorkspaceButton.Enable = 'off';
            app.ExportToWorkspaceButton.Text = 'Export To Workspace';
            app.ExportToONNXButton = uibutton(app.ExportTab, 'push');
            app.ExportToONNXButton.Icon = 'mlogo.png';
            app.ExportToONNXButton.Enable = 'off';
            app.ExportToONNXButton.Text = 'Export To ONNX';
            app.GPUCoder = uibutton(app.ExportTab, 'push');
            app.GPUCoder.Icon = 'gpu-icon-gpu-pictograph-gpu-web-icon-gpu-icon-vector-gpu-icon-eps-gpu-icon-illustration_15267-4.jpg';
            app.GPUCoder.Enable = 'off';
            app.GPUCoder.Text = {'GPU'; ' Code Generation'};
            app.ExportToMatFile = uibutton(app.ExportTab, 'push');
            app.ExportToMatFile.Icon = 'cell-0-0-480.png';
            app.ExportToMatFile.Enable = 'off';
            app.ExportToMatFile.Text = 'Export To MAT File';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '分类结果';
            app.StatusofRunningLampLabel = uilabel(app.Panel_2);
            app.StatusofRunningLampLabel.Text = 'Status of Running';
            app.StatusofRunningLamp = uilamp(app.Panel_2);
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '时域波形显示';
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CommandLogLabel = uilabel(app.Panel_3);
            app.CommandLogLabel.Text = 'Command Log';
            app.TextArea = uitextarea(app.Panel_3);
            app.Panel_9 = uipanel(app.UIFigure);
            app.Panel_9.Title = '特征统计图';
            app.UIAxes_2 = uiaxes(app.Panel_9);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.Panel_9);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end